classdef ME5402_CA1_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Q1aTab                         matlab.ui.container.Tab
        ComputerotationmatrixLabel     matlab.ui.control.Label
        UnitdegreeLabel                matlab.ui.control.Label
        alphaEditFieldLabel            matlab.ui.control.Label
        alphaEditField                 matlab.ui.control.NumericEditField
        betaEditFieldLabel             matlab.ui.control.Label
        betaEditField                  matlab.ui.control.NumericEditField
        gammaEditFieldLabel            matlab.ui.control.Label
        gammaEditField                 matlab.ui.control.NumericEditField
        EditField_11Label              matlab.ui.control.Label
        EditField_11                   matlab.ui.control.NumericEditField
        EditField2_2Label              matlab.ui.control.Label
        EditField2_2                   matlab.ui.control.NumericEditField
        EditField3_2Label              matlab.ui.control.Label
        EditField3_2                   matlab.ui.control.NumericEditField
        aboutyaxisLabel                matlab.ui.control.Label
        Orderchoosefrom13Label         matlab.ui.control.Label
        aboutxaxisLabel                matlab.ui.control.Label
        aboutzaxisLabel                matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        UITable_2                      matlab.ui.control.Table
        ResultofrotationmatrixLabel_2  matlab.ui.control.Label
        Q1bTab                         matlab.ui.container.Tab
        FixedAnglestoRotationMatrixLabel  matlab.ui.control.Label
        CalcualteButton                matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        UnitDegreeLabel                matlab.ui.control.Label
        PleaseenterrollpitchandyawdataLabel  matlab.ui.control.Label
        ResultofrotationmatrixLabel    matlab.ui.control.Label
        RollEditFieldLabel             matlab.ui.control.Label
        RollEditField                  matlab.ui.control.NumericEditField
        PitchEditFieldLabel            matlab.ui.control.Label
        PitchEditField                 matlab.ui.control.NumericEditField
        YawEditFieldLabel              matlab.ui.control.Label
        YawEditField                   matlab.ui.control.NumericEditField
        RotationMatrixtoFixedAnglesLabel  matlab.ui.control.Label
        PleaseenterrotationmatrixdataLabel  matlab.ui.control.Label
        EditFieldLabel                 matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        EditField_2Label               matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3Label               matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_4Label               matlab.ui.control.Label
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_6Label               matlab.ui.control.Label
        EditField_6                    matlab.ui.control.NumericEditField
        EditField_9Label               matlab.ui.control.Label
        EditField_9                    matlab.ui.control.NumericEditField
        EditField_5Label               matlab.ui.control.Label
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_7Label               matlab.ui.control.Label
        EditField_7                    matlab.ui.control.NumericEditField
        EditField_8Label               matlab.ui.control.Label
        EditField_8                    matlab.ui.control.NumericEditField
        CalcualteButton_2              matlab.ui.control.Button
        ResultofanglesLabel            matlab.ui.control.Label
        RollEditField_2Label           matlab.ui.control.Label
        RollEditField_2                matlab.ui.control.NumericEditField
        PitchEditField_2Label          matlab.ui.control.Label
        PitchEditField_2               matlab.ui.control.NumericEditField
        YawEditField_2Label            matlab.ui.control.Label
        YawEditField_2                 matlab.ui.control.NumericEditField
        Sol1Label                      matlab.ui.control.Label
        Sol2Label                      matlab.ui.control.Label
        RollEditField_3Label           matlab.ui.control.Label
        RollEditField_3                matlab.ui.control.NumericEditField
        PitchEditField_3Label          matlab.ui.control.Label
        PitchEditField_3               matlab.ui.control.NumericEditField
        YawEditField_3Label            matlab.ui.control.Label
        YawEditField_3                 matlab.ui.control.NumericEditField
        Q2Tab                          matlab.ui.container.Tab
        InversekinematicsofPUMA600Label  matlab.ui.control.Label
        UnitdegreeLabel_2              matlab.ui.control.Label
        OrientationofendpointLabel     matlab.ui.control.Label
        PositionofendpointLabel        matlab.ui.control.Label
        CalculateButton_2              matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        ResultstatusEditFieldLabel     matlab.ui.control.Label
        ResultstatusEditField          matlab.ui.control.EditField
        Joint_1EditFieldLabel          matlab.ui.control.Label
        Joint_1EditField               matlab.ui.control.NumericEditField
        Joint_2EditFieldLabel          matlab.ui.control.Label
        Joint_2EditField               matlab.ui.control.NumericEditField
        Joint_3EditFieldLabel          matlab.ui.control.Label
        Joint_3EditField               matlab.ui.control.NumericEditField
        Joint_4EditFieldLabel          matlab.ui.control.Label
        Joint_4EditField               matlab.ui.control.NumericEditField
        Joint_5EditFieldLabel          matlab.ui.control.Label
        Joint_5EditField               matlab.ui.control.NumericEditField
        Joint_6EditFieldLabel          matlab.ui.control.Label
        Joint_6EditField               matlab.ui.control.NumericEditField
        EditField4                     matlab.ui.control.NumericEditField
        EditField4_2                   matlab.ui.control.NumericEditField
        EditField4_3                   matlab.ui.control.NumericEditField
        EditField4_4                   matlab.ui.control.NumericEditField
        EditField4_5                   matlab.ui.control.NumericEditField
        EditField4_6                   matlab.ui.control.NumericEditField
        EditField4_7                   matlab.ui.control.NumericEditField
        EditField4_8                   matlab.ui.control.NumericEditField
        EditField4_9                   matlab.ui.control.NumericEditField
        EditField4_10                  matlab.ui.control.NumericEditField
        EditField4_11                  matlab.ui.control.NumericEditField
        EditField4_12                  matlab.ui.control.NumericEditField
        Q3Tab_2                        matlab.ui.container.Tab
        CalculateButton_4              matlab.ui.control.Button
        ComputetheequivalentjointstorquesforcesLabel_2  matlab.ui.control.Label
        Theta1EditField_2Label         matlab.ui.control.Label
        Theta1EditField_2              matlab.ui.control.NumericEditField
        JointparametersLabel_2         matlab.ui.control.Label
        d2EditField_2Label             matlab.ui.control.Label
        d2EditField_2                  matlab.ui.control.NumericEditField
        d3EditFieldLabel               matlab.ui.control.Label
        d3EditField                    matlab.ui.control.NumericEditField
        EndpointforceLabel             matlab.ui.control.Label
        f1EditFieldLabel               matlab.ui.control.Label
        f1EditField                    matlab.ui.control.NumericEditField
        f2EditFieldLabel               matlab.ui.control.Label
        f2EditField                    matlab.ui.control.NumericEditField
        f3EditFieldLabel               matlab.ui.control.Label
        f3EditField                    matlab.ui.control.NumericEditField
        n1EditFieldLabel               matlab.ui.control.Label
        n1EditField                    matlab.ui.control.NumericEditField
        n2EditFieldLabel               matlab.ui.control.Label
        n2EditField                    matlab.ui.control.NumericEditField
        n3EditFieldLabel               matlab.ui.control.Label
        n3EditField                    matlab.ui.control.NumericEditField
        JointforceLabel                matlab.ui.control.Label
        Joint1EditFieldLabel           matlab.ui.control.Label
        Joint1EditField                matlab.ui.control.NumericEditField
        Joint2EditFieldLabel           matlab.ui.control.Label
        Joint2EditField                matlab.ui.control.NumericEditField
        Joint3EditFieldLabel           matlab.ui.control.Label
        Joint3EditField                matlab.ui.control.NumericEditField
        Q4ciTab                        matlab.ui.container.Tab
        UIAxes_2                       matlab.ui.control.UIAxes
        PlotButton                     matlab.ui.control.Button
        ThecircleontheworksurfacewehavedrawnwiththetoolLabel  matlab.ui.control.Label
        Q4ciiiTab_2                    matlab.ui.container.Tab
        UIAxes_4                       matlab.ui.control.UIAxes
        PlotButton_3                   matlab.ui.control.Button
        TherespectivejointanglesversustimeLabel  matlab.ui.control.Label
        Waitforabout5spleaseLabel_3    matlab.ui.control.Label
        Q4civTab                       matlab.ui.container.Tab
        UIAxes_3                       matlab.ui.control.UIAxes
        PlotButton_2                   matlab.ui.control.Button
        TherespectivejointratesversustimeLabel  matlab.ui.control.Label
        Waitforabout5spleaseLabel_2    matlab.ui.control.Label
        Q4cvTab                        matlab.ui.container.Tab
        UIAxes_5                       matlab.ui.control.UIAxes
        PlotButton_4                   matlab.ui.control.Button
        TherespectivejointtorquesversustimeLabel  matlab.ui.control.Label
        Waitforabout5spleaseLabel      matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.UITable.Data = nan(3,3);    %设置表格大小
        axis(app.UIAxes,'off');
        end

        % Button pushed function: CalcualteButton
        function CalcualteButtonPushed(app, event)
            roll = app.RollEditField.Value;
            pitch = app.PitchEditField.Value;
            yaw = app.YawEditField.Value;
            Matrix = xyz_to_rot(roll,pitch,yaw);
            app.UITable.Data = Matrix;
        end

        % Button pushed function: CalcualteButton_2
        function CalcualteButton_2Pushed(app, event)
            Matrix = [app.EditField.Value,app.EditField_2.Value,app.EditField_3.Value;
                    app.EditField_4.Value,app.EditField_6.Value,app.EditField_9.Value;
                    app.EditField_5.Value,app.EditField_7.Value,app.EditField_8.Value];
            XYZ = rot_to_XYZ(Matrix);
            app.RollEditField_2.Value = XYZ(1,1);
            app.RollEditField_3.Value = XYZ(2,1);
            app.PitchEditField_2.Value = XYZ(1,2);
            app.PitchEditField_3.Value = XYZ(2,2);
            app.YawEditField_2.Value = XYZ(1,3);
            app.YawEditField_3.Value = XYZ(2,3);
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            alpha=app.alphaEditField.Value;
            beta=app.betaEditField.Value;
            gamma=app.gammaEditField.Value;
            order = [app.EditField_11.Value app.EditField2_2.Value app.EditField3_2.Value];
            rot_matrix = y_x_rot(beta, alpha, gamma, order);
            app.UITable_2.Data = rot_matrix;
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)

                angles = inverse_kinematics([app.EditField4.Value,app.EditField4_2.Value,app.EditField4_3.Value,app.EditField4_4.Value;
                    app.EditField4_5.Value,app.EditField4_6.Value,app.EditField4_7.Value,app.EditField4_8.Value;
                    app.EditField4_9.Value,app.EditField4_10.Value,app.EditField4_11.Value,app.EditField4_12.Value
                     0,0,0,1]);
               if length(angles) == 0
                   app.ResultstatusEditField.Value ='Out of Workspace';
                   app.Joint_1EditField.Value = 0;
                   app.Joint_2EditField.Value = 0;
                   app.Joint_3EditField.Value = 0;
                   app.Joint_4EditField.Value = 0;
                   app.Joint_5EditField.Value = 0;
                   app.Joint_6EditField.Value = 0;
                   imshow('1.png','Parent',app.UIAxes);
               else
                   app.ResultstatusEditField.Value ='Solution exist';
                    
               app.Joint_1EditField.Value = angles(1);
               app.Joint_2EditField.Value = angles(2);
               app.Joint_3EditField.Value = angles(3);
               app.Joint_4EditField.Value = angles(4);
               app.Joint_5EditField.Value = angles(5);
               app.Joint_6EditField.Value = angles(6);
              
               Figure= plot_q2(angles(1),angles(2),angles(3),angles(4),angles(5),angles(6));
               imshow(Figure,'Parent',app.UIAxes);
               end
        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
            joint_force3=ca1_q3(app.Theta1EditField_2.Value,app.d2EditField_2.Value,app.d3EditField.Value,...
            app.f1EditField.Value,app.f2EditField.Value,app.f3EditField.Value,app.n1EditField.Value,app.n2EditField.Value,app.n3EditField.Value);
            app.Joint1EditField.Value = joint_force3(1);
            app.Joint2EditField.Value = joint_force3(2);
            app.Joint3EditField.Value = joint_force3(3);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

                for t = 0:0.2:6.5
                Figure = p4_plot(t);
                imshow(Figure,'Parent',app.UIAxes_2);
                end
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            Figure=q4(1);
            imshow(Figure,'Parent',app.UIAxes_3);
        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            Figure=q4(2);
            imshow(Figure,'Parent',app.UIAxes_4);
        end

        % Button pushed function: PlotButton_4
        function PlotButton_4Pushed(app, event)
            Figure=q4(3);
            imshow(Figure,'Parent',app.UIAxes_5);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Q1aTab = uitab(app.TabGroup);
            app.Q1aTab.Title = 'Q1a';
            app.ComputerotationmatrixLabel = uilabel(app.Q1aTab);
            app.ComputerotationmatrixLabel.Text = 'Compute rotation matrix';
            app.UnitdegreeLabel = uilabel(app.Q1aTab);
            app.UnitdegreeLabel.Text = 'Unit: degree';
            app.alphaEditFieldLabel = uilabel(app.Q1aTab);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.Q1aTab, 'numeric');
            app.betaEditFieldLabel = uilabel(app.Q1aTab);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.Q1aTab, 'numeric');
            app.gammaEditFieldLabel = uilabel(app.Q1aTab);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.Q1aTab, 'numeric');
            app.EditField_11Label = uilabel(app.Q1aTab);
            app.EditField_11Label.Text = 'Edit Field';
            app.EditField_11 = uieditfield(app.Q1aTab, 'numeric');
            app.EditField_11.Limits = [1 3];
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_11.Value = 1;
            app.EditField2_2Label = uilabel(app.Q1aTab);
            app.EditField2_2Label.Text = 'Edit Field2';
            app.EditField2_2 = uieditfield(app.Q1aTab, 'numeric');
            app.EditField2_2.Limits = [1 3];
            app.EditField2_2.Value = 2;
            app.EditField3_2Label = uilabel(app.Q1aTab);
            app.EditField3_2Label.Text = 'Edit Field3';
            app.EditField3_2 = uieditfield(app.Q1aTab, 'numeric');
            app.EditField3_2.Limits = [1 3];
            app.EditField3_2.Value = 3;
            app.aboutyaxisLabel = uilabel(app.Q1aTab);
            app.aboutyaxisLabel.Text = 'about y axis';
            app.Orderchoosefrom13Label = uilabel(app.Q1aTab);
            app.Orderchoosefrom13Label.Text = 'Order(choose from 1-3)';
            app.aboutxaxisLabel = uilabel(app.Q1aTab);
            app.aboutxaxisLabel.Text = 'about x axis';
            app.aboutzaxisLabel = uilabel(app.Q1aTab);
            app.aboutzaxisLabel.Text = 'about z axis';
            app.CalculateButton = uibutton(app.Q1aTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UITable_2 = uitable(app.Q1aTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [false false true];
            app.ResultofrotationmatrixLabel_2 = uilabel(app.Q1aTab);
            app.ResultofrotationmatrixLabel_2.Text = 'Result of rotation matrix';
            app.Q1bTab = uitab(app.TabGroup);
            app.Q1bTab.Title = 'Q1b';
            app.FixedAnglestoRotationMatrixLabel = uilabel(app.Q1bTab);
            app.FixedAnglestoRotationMatrixLabel.Text = ' Fixed Angles to Rotation Matrix';
            app.CalcualteButton = uibutton(app.Q1bTab, 'push');
            app.CalcualteButton.ButtonPushedFcn = createCallbackFcn(app, @CalcualteButtonPushed, true);
            app.CalcualteButton.Text = 'Calcualte';
            app.UITable = uitable(app.Q1bTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true];
            app.UnitDegreeLabel = uilabel(app.Q1bTab);
            app.UnitDegreeLabel.Text = 'Unit：Degree';
            app.PleaseenterrollpitchandyawdataLabel = uilabel(app.Q1bTab);
            app.PleaseenterrollpitchandyawdataLabel.Text = 'Please enter roll, pitch and yaw data';
            app.ResultofrotationmatrixLabel = uilabel(app.Q1bTab);
            app.ResultofrotationmatrixLabel.Text = 'Result of rotation matrix';
            app.RollEditFieldLabel = uilabel(app.Q1bTab);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.Q1bTab, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.Q1bTab);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.Q1bTab, 'numeric');
            app.YawEditFieldLabel = uilabel(app.Q1bTab);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.Q1bTab, 'numeric');
            app.RotationMatrixtoFixedAnglesLabel = uilabel(app.Q1bTab);
            app.RotationMatrixtoFixedAnglesLabel.Text = 'Rotation Matrix to Fixed Angles';
            app.PleaseenterrotationmatrixdataLabel = uilabel(app.Q1bTab);
            app.PleaseenterrotationmatrixdataLabel.Text = 'Please enter rotation matrix data';
            app.EditFieldLabel = uilabel(app.Q1bTab);
            app.EditFieldLabel.Text = '(1,1)';
            app.EditField = uieditfield(app.Q1bTab, 'numeric');
            app.EditField_2Label = uilabel(app.Q1bTab);
            app.EditField_2Label.Text = '(1,2)';
            app.EditField_2 = uieditfield(app.Q1bTab, 'numeric');
            app.EditField_3Label = uilabel(app.Q1bTab);
            app.EditField_3Label.Text = '(1,3)';
            app.EditField_3 = uieditfield(app.Q1bTab, 'numeric');
            app.EditField_4Label = uilabel(app.Q1bTab);
            app.EditField_4Label.Text = '(2,1)';
            app.EditField_4 = uieditfield(app.Q1bTab, 'numeric');
            app.EditField_6Label = uilabel(app.Q1bTab);
            app.EditField_6Label.Text = '(2,2)';
            app.EditField_6 = uieditfield(app.Q1bTab, 'numeric');
            app.EditField_9Label = uilabel(app.Q1bTab);
            app.EditField_9Label.Text = '(2,3)';
            app.EditField_9 = uieditfield(app.Q1bTab, 'numeric');
            app.EditField_5Label = uilabel(app.Q1bTab);
            app.EditField_5Label.Text = '(3,1)';
            app.EditField_5 = uieditfield(app.Q1bTab, 'numeric');
            app.EditField_7Label = uilabel(app.Q1bTab);
            app.EditField_7Label.Text = '(3,2)';
            app.EditField_7 = uieditfield(app.Q1bTab, 'numeric');
            app.EditField_8Label = uilabel(app.Q1bTab);
            app.EditField_8Label.Text = '(3,3)';
            app.EditField_8 = uieditfield(app.Q1bTab, 'numeric');
            app.CalcualteButton_2 = uibutton(app.Q1bTab, 'push');
            app.CalcualteButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcualteButton_2Pushed, true);
            app.CalcualteButton_2.Text = 'Calcualte';
            app.ResultofanglesLabel = uilabel(app.Q1bTab);
            app.ResultofanglesLabel.Text = 'Result of angles';
            app.RollEditField_2Label = uilabel(app.Q1bTab);
            app.RollEditField_2Label.Text = 'Roll';
            app.RollEditField_2 = uieditfield(app.Q1bTab, 'numeric');
            app.PitchEditField_2Label = uilabel(app.Q1bTab);
            app.PitchEditField_2Label.Text = 'Pitch';
            app.PitchEditField_2 = uieditfield(app.Q1bTab, 'numeric');
            app.YawEditField_2Label = uilabel(app.Q1bTab);
            app.YawEditField_2Label.Text = 'Yaw';
            app.YawEditField_2 = uieditfield(app.Q1bTab, 'numeric');
            app.Sol1Label = uilabel(app.Q1bTab);
            app.Sol1Label.Text = 'Sol.1';
            app.Sol2Label = uilabel(app.Q1bTab);
            app.Sol2Label.Text = 'Sol.2';
            app.RollEditField_3Label = uilabel(app.Q1bTab);
            app.RollEditField_3Label.Text = 'Roll';
            app.RollEditField_3 = uieditfield(app.Q1bTab, 'numeric');
            app.PitchEditField_3Label = uilabel(app.Q1bTab);
            app.PitchEditField_3Label.Text = 'Pitch';
            app.PitchEditField_3 = uieditfield(app.Q1bTab, 'numeric');
            app.YawEditField_3Label = uilabel(app.Q1bTab);
            app.YawEditField_3Label.Text = 'Yaw';
            app.YawEditField_3 = uieditfield(app.Q1bTab, 'numeric');
            app.Q2Tab = uitab(app.TabGroup);
            app.Q2Tab.Title = 'Q2';
            app.InversekinematicsofPUMA600Label = uilabel(app.Q2Tab);
            app.InversekinematicsofPUMA600Label.Text = 'Inverse kinematics of PUMA600';
            app.UnitdegreeLabel_2 = uilabel(app.Q2Tab);
            app.UnitdegreeLabel_2.Text = 'Unit: degree';
            app.OrientationofendpointLabel = uilabel(app.Q2Tab);
            app.OrientationofendpointLabel.Text = 'Orientation of endpoint';
            app.PositionofendpointLabel = uilabel(app.Q2Tab);
            app.PositionofendpointLabel.Text = 'Position of endpoint';
            app.CalculateButton_2 = uibutton(app.Q2Tab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.UIAxes = uiaxes(app.Q2Tab);
            title(app.UIAxes, 'PMUA600')
            app.ResultstatusEditFieldLabel = uilabel(app.Q2Tab);
            app.ResultstatusEditFieldLabel.Text = 'Result status';
            app.ResultstatusEditField = uieditfield(app.Q2Tab, 'text');
            app.Joint_1EditFieldLabel = uilabel(app.Q2Tab);
            app.Joint_1EditFieldLabel.Text = 'Joint_1';
            app.Joint_1EditField = uieditfield(app.Q2Tab, 'numeric');
            app.Joint_2EditFieldLabel = uilabel(app.Q2Tab);
            app.Joint_2EditFieldLabel.Text = 'Joint_2';
            app.Joint_2EditField = uieditfield(app.Q2Tab, 'numeric');
            app.Joint_3EditFieldLabel = uilabel(app.Q2Tab);
            app.Joint_3EditFieldLabel.Text = 'Joint_3';
            app.Joint_3EditField = uieditfield(app.Q2Tab, 'numeric');
            app.Joint_4EditFieldLabel = uilabel(app.Q2Tab);
            app.Joint_4EditFieldLabel.Text = 'Joint_4';
            app.Joint_4EditField = uieditfield(app.Q2Tab, 'numeric');
            app.Joint_5EditFieldLabel = uilabel(app.Q2Tab);
            app.Joint_5EditFieldLabel.Text = 'Joint_5';
            app.Joint_5EditField = uieditfield(app.Q2Tab, 'numeric');
            app.Joint_6EditFieldLabel = uilabel(app.Q2Tab);
            app.Joint_6EditFieldLabel.Text = 'Joint_6';
            app.Joint_6EditField = uieditfield(app.Q2Tab, 'numeric');
            app.EditField4 = uieditfield(app.Q2Tab, 'numeric');
            app.EditField4_2 = uieditfield(app.Q2Tab, 'numeric');
            app.EditField4_3 = uieditfield(app.Q2Tab, 'numeric');
            app.EditField4_4 = uieditfield(app.Q2Tab, 'numeric');
            app.EditField4_5 = uieditfield(app.Q2Tab, 'numeric');
            app.EditField4_6 = uieditfield(app.Q2Tab, 'numeric');
            app.EditField4_7 = uieditfield(app.Q2Tab, 'numeric');
            app.EditField4_8 = uieditfield(app.Q2Tab, 'numeric');
            app.EditField4_9 = uieditfield(app.Q2Tab, 'numeric');
            app.EditField4_10 = uieditfield(app.Q2Tab, 'numeric');
            app.EditField4_11 = uieditfield(app.Q2Tab, 'numeric');
            app.EditField4_12 = uieditfield(app.Q2Tab, 'numeric');
            app.Q3Tab_2 = uitab(app.TabGroup);
            app.Q3Tab_2.Title = 'Q3';
            app.CalculateButton_4 = uibutton(app.Q3Tab_2, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = 'Calculate';
            app.ComputetheequivalentjointstorquesforcesLabel_2 = uilabel(app.Q3Tab_2);
            app.ComputetheequivalentjointstorquesforcesLabel_2.Text = ' Compute the equivalent joints’ torques/forces';
            app.Theta1EditField_2Label = uilabel(app.Q3Tab_2);
            app.Theta1EditField_2Label.Text = 'Theta1';
            app.Theta1EditField_2 = uieditfield(app.Q3Tab_2, 'numeric');
            app.JointparametersLabel_2 = uilabel(app.Q3Tab_2);
            app.JointparametersLabel_2.Text = 'Joint parameters';
            app.d2EditField_2Label = uilabel(app.Q3Tab_2);
            app.d2EditField_2Label.Text = 'd2';
            app.d2EditField_2 = uieditfield(app.Q3Tab_2, 'numeric');
            app.d2EditField_2.Value = 1;
            app.d3EditFieldLabel = uilabel(app.Q3Tab_2);
            app.d3EditFieldLabel.Text = 'd3';
            app.d3EditField = uieditfield(app.Q3Tab_2, 'numeric');
            app.d3EditField.Value = 1;
            app.EndpointforceLabel = uilabel(app.Q3Tab_2);
            app.EndpointforceLabel.Text = 'Endpoint force';
            app.f1EditFieldLabel = uilabel(app.Q3Tab_2);
            app.f1EditFieldLabel.Text = 'f1';
            app.f1EditField = uieditfield(app.Q3Tab_2, 'numeric');
            app.f1EditField.Value = 1;
            app.f2EditFieldLabel = uilabel(app.Q3Tab_2);
            app.f2EditFieldLabel.Text = 'f2';
            app.f2EditField = uieditfield(app.Q3Tab_2, 'numeric');
            app.f2EditField.Value = 2;
            app.f3EditFieldLabel = uilabel(app.Q3Tab_2);
            app.f3EditFieldLabel.Text = 'f3';
            app.f3EditField = uieditfield(app.Q3Tab_2, 'numeric');
            app.f3EditField.Value = 3;
            app.n1EditFieldLabel = uilabel(app.Q3Tab_2);
            app.n1EditFieldLabel.Text = 'n1';
            app.n1EditField = uieditfield(app.Q3Tab_2, 'numeric');
            app.n2EditFieldLabel = uilabel(app.Q3Tab_2);
            app.n2EditFieldLabel.Text = 'n2';
            app.n2EditField = uieditfield(app.Q3Tab_2, 'numeric');
            app.n3EditFieldLabel = uilabel(app.Q3Tab_2);
            app.n3EditFieldLabel.Text = 'n3';
            app.n3EditField = uieditfield(app.Q3Tab_2, 'numeric');
            app.JointforceLabel = uilabel(app.Q3Tab_2);
            app.JointforceLabel.Text = 'Joint force';
            app.Joint1EditFieldLabel = uilabel(app.Q3Tab_2);
            app.Joint1EditFieldLabel.Text = 'Joint1';
            app.Joint1EditField = uieditfield(app.Q3Tab_2, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.Q3Tab_2);
            app.Joint2EditFieldLabel.Text = 'Joint2';
            app.Joint2EditField = uieditfield(app.Q3Tab_2, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.Q3Tab_2);
            app.Joint3EditFieldLabel.Text = 'Joint3';
            app.Joint3EditField = uieditfield(app.Q3Tab_2, 'numeric');
            app.Q4ciTab = uitab(app.TabGroup);
            app.Q4ciTab.Title = 'Q4c(i)';
            app.UIAxes_2 = uiaxes(app.Q4ciTab);
            app.PlotButton = uibutton(app.Q4ciTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ThecircleontheworksurfacewehavedrawnwiththetoolLabel = uilabel(app.Q4ciTab);
            app.ThecircleontheworksurfacewehavedrawnwiththetoolLabel.Text = ' The circle on the work surface we have drawn with the tool';
            app.Q4ciiiTab_2 = uitab(app.TabGroup);
            app.Q4ciiiTab_2.Title = 'Q4c(iii)';
            app.UIAxes_4 = uiaxes(app.Q4ciiiTab_2);
            app.PlotButton_3 = uibutton(app.Q4ciiiTab_2, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.TherespectivejointanglesversustimeLabel = uilabel(app.Q4ciiiTab_2);
            app.TherespectivejointanglesversustimeLabel.Text = 'The respective joint angles versus time';
            app.Waitforabout5spleaseLabel_3 = uilabel(app.Q4ciiiTab_2);
            app.Waitforabout5spleaseLabel_3.Text = '(Wait for about 5s please)';
            app.Q4civTab = uitab(app.TabGroup);
            app.Q4civTab.Title = 'Q4c(iv)';
            app.UIAxes_3 = uiaxes(app.Q4civTab);
            app.PlotButton_2 = uibutton(app.Q4civTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.TherespectivejointratesversustimeLabel = uilabel(app.Q4civTab);
            app.TherespectivejointratesversustimeLabel.Text = ' The respective joint rates versus time';
            app.Waitforabout5spleaseLabel_2 = uilabel(app.Q4civTab);
            app.Waitforabout5spleaseLabel_2.Text = '(Wait for about 5s please)';
            app.Q4cvTab = uitab(app.TabGroup);
            app.Q4cvTab.Title = 'Q4c(v)';
            app.UIAxes_5 = uiaxes(app.Q4cvTab);
            app.PlotButton_4 = uibutton(app.Q4cvTab, 'push');
            app.PlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_4Pushed, true);
            app.PlotButton_4.Text = 'Plot';
            app.TherespectivejointtorquesversustimeLabel = uilabel(app.Q4cvTab);
            app.TherespectivejointtorquesversustimeLabel.Text = ' The respective joint torques versus time';
            app.Waitforabout5spleaseLabel = uilabel(app.Q4cvTab);
            app.Waitforabout5spleaseLabel.Text = '(Wait for about 5s please)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ME5402_CA1_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end